classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ConstantededragEditFieldLabel  matlab.ui.control.Label
        ConstantededragEditField       matlab.ui.control.NumericEditField
        TssEditFieldLabel              matlab.ui.control.Label
        TssEditField                   matlab.ui.control.NumericEditField
        Densidaddelairekgm3EditFieldLabel  matlab.ui.control.Label
        Densidaddelairekgm3EditField   matlab.ui.control.NumericEditField
        AreatransversalEditFieldLabel  matlab.ui.control.Label
        AreatransversalEditField       matlab.ui.control.NumericEditField
        velocidadinicialmsEditFieldLabel  matlab.ui.control.Label
        velocidadinicialmsEditField    matlab.ui.control.NumericEditField
        AngulogradosEditFieldLabel     matlab.ui.control.Label
        AngulogradosEditField          matlab.ui.control.NumericEditField
        MasakgEditFieldLabel           matlab.ui.control.Label
        MasakgEditField                matlab.ui.control.NumericEditField
        ymEditFieldLabel               matlab.ui.control.Label
        ymEditField                    matlab.ui.control.NumericEditField
        xmEditFieldLabel               matlab.ui.control.Label
        xmEditField                    matlab.ui.control.NumericEditField
        AlfaEditFieldLabel             matlab.ui.control.Label
        AlfaEditField                  matlab.ui.control.NumericEditField
        maxymEditFieldLabel            matlab.ui.control.Label
        maxymEditField                 matlab.ui.control.NumericEditField
        XinstantaneamEditFieldLabel    matlab.ui.control.Label
        XinstantaneamEditField         matlab.ui.control.NumericEditField
        YinstantaneamEditFieldLabel    matlab.ui.control.Label
        YinstantaneamEditField         matlab.ui.control.NumericEditField
        velocidadinstaneaXmsEditFieldLabel  matlab.ui.control.Label
        velocidadinstaneaXmsEditField  matlab.ui.control.NumericEditField
        velocidadinstantaneaYmsEditFieldLabel  matlab.ui.control.Label
        velocidadinstantaneaYmsEditField  matlab.ui.control.NumericEditField
        maxxmEditFieldLabel            matlab.ui.control.Label
        maxxmEditField                 matlab.ui.control.NumericEditField
        ValoresInicialesLabel          matlab.ui.control.Label
        ValoresinstantaneosLabel       matlab.ui.control.Label
        TiempofinaslsEditFieldLabel    matlab.ui.control.Label
        TiempofinaslsEditField         matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        EmpezarButton                  matlab.ui.control.Button
        EmpezaraleatorioButton         matlab.ui.control.Button
        AleatorioTextAreaLabel         matlab.ui.control.Label
        AleatorioTextArea              matlab.ui.control.TextArea
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EmpezarButton
        function EmpezarButtonPushed(app, event)
           %% Parametros RESISTENCIA DEL AIRE
            v0 = app.velocidadinicialmsEditField.Value;
            mangulo = app.AngulogradosEditField.Value;
            angulo = mangulo*pi/180;
            ay0 = -9.81;
            ax0 = 0;  
            x0 = app.xmEditField.Value;
            y0 = app.ymEditField.Value;
            Ts = app.TssEditField.Value;    
            KD = 1/2*app.ConstantededragEditField.Value*app.Densidaddelairekgm3EditField.Value*app.AreatransversalEditField.Value;   
            n = app.AlfaEditField.Value;  
            m = app.MasakgEditField.Value;
            %% inicializacion de variables  RESISTENCIA DEL AIRE
            v0x = v0*cos(angulo);
            v0y = v0*sin(angulo);
            vx = v0x;
            vy = v0y;
            v = v0;
            x = x0;
            y = y0;
            ax = ax0;
            ay = ay0;
            index = 1;
            %% vectores de posición  RESISTENCIA DEL AIRE
            vectorx(index) = x;
            vectory(index) = y;
            %% Parametros  VACIO
            v01 = v;
            angulo1 = angulo;
            x01 = x0;
            y01 = y0;
            %% inicializacion de variables  VACIO
            v0x1 = v01*cos(angulo1);
            v0y1 = v01*sin(angulo1);
            
            vx1 = v0x1;
            vy1 = v0y1;
            x1 = x01;
            y1 = y01;
            index1 = 1;
            vectorx1(index1) = x1;
            vectory1(index1) = y1;
            max_y = 0;
            max_x = 0;
            max_t = 0;
            
            max_y1 = 0;
            max_x1 = 0;
            max_t1 = 0;
            t = 0;
            t1 = 0;


            %% Iniciliación del ciclo while  
            while y >= 0 
                %% RESISTENCIA DEL AIRE
                %%Declaración del angulo anterior 
                angulo_anterior = angulo;
                ax_anterior = 0;
                ay_anterior = -9.81;
                
                vx_anterior = vx;
                vx = vx + .5*(ax_anterior+ax)*Ts;
                x = x + vx_anterior * Ts + Ts^2*0.5*ax_anterior;
                
                vy_anterior = vy;
                vy = vy + .5*(ay_anterior+ay)*Ts;
                y = y + vy_anterior * Ts + Ts^2*0.5*ay_anterior;
                
                angulo = atan(vy/vx);
                v = sqrt(vx^2+vy^2);
                 
                ax = ax_anterior -(KD/m)*v^n*cos(angulo_anterior);
                ay = ay_anterior -(KD/m)*v^n*sin(angulo_anterior);
                
                index = index + 1;
                vectorx(index) = x;
                vectory(index) = y;
                
                %% SIN RESISTENCIA DEL AIRE
                %% declaramos la posición y velocidad en x. VACIO
                vx1_anterior1 = vx1;
                vx1 = vx1 + Ts*0;
                x1 = x1 + vx1_anterior1 * Ts+ (Ts^2)/2*0;
                t1 = t1  + Ts;


                %% Declaramos la posición y velocidad de y. VACIO
                vy_anterior1 = vy1;
                vy1 = vy1 + Ts*(-9.81);
                y1 = y1 + vy_anterior1 * Ts+ (Ts^2)/2*(-9.81);
                
                %% Declaración del index e introducirlos a los vectores de posición x,y  VACIO
                index1 = index1 + 1;
                vectorx1(index1) = x1;
                vectory1(index1) = y1;
                %% Mostar la gráfica de posición con resistencia del aire
                plot(app.UIAxes,vectorx,vectory,"b",vectorx1,vectory1,"r");
                if y > max_y
                    max_y = y;
                if x > max_x
                    max_x = x;
                if t > max_t
                    max_t = t1;
                end
                end
                end
             if y1 > max_y1
                    max_y1 = y1;
             if x1 > max_x1
                     max_x1 = x1;
             if t1 > max_t
                    max_t1 = t1;
             end
             end
             end
                 
                app.maxxmEditField.Value = max_x; 
                app.maxymEditField.Value = max_y;
                app.velocidadinstaneaXmsEditField.Value = vx;
                app.velocidadinstantaneaYmsEditField.Value = vy;
                app.XinstantaneamEditField.Value = x;
                app.YinstantaneamEditField.Value = y;
                app.TiempofinaslsEditField.Value = max_t1;
                pause(0.1)
            end
        end

        % Button pushed function: EmpezaraleatorioButton
        function EmpezaraleatorioButtonPushed(app, event)
            %% Parametros RESISTENCIA DEL AIRE        ALEATORIOS
                v0 = randi(100);
                mangulo1 = randi(90);
                mangulo2 = randi(90);
                mangulo3 = randi(90);
                angulo1 = mangulo1*pi/180;
                angulo2 = mangulo2*pi/180;
                angulo3 = mangulo3*pi/180;
                ay0 = -9.81;
                ax0 = 0;  
                x0 = app.xmEditField.Value;
                y0 = app.ymEditField.Value;
                Ts = app.TssEditField.Value;    
                KD = 1/2*app.ConstantededragEditField.Value*app.Densidaddelairekgm3EditField.Value*app.AreatransversalEditField.Value;
                n = app.AlfaEditField.Value;  
                m1 = app.MasakgEditField.Value;
                m2 = m1;
                m3 = m1;
                %% inicializacion de variables  RESISTENCIA DEL AIRE  variable 1
                v0x1 = v0*cos(angulo1);
                v0y1 = v0*sin(angulo1);
                vx1 = v0x1;
                vy1 = v0y1;
                
                x1 = x0;
                y1 = y0;
                ax1 = ax0;
                ay1 = ay0;
                index1 = 1;
                %% inicializacion de variables  RESISTENCIA DEL AIRE  variable 2
                v0x2 = v0*cos(angulo2);
                v0y2 = v0*sin(angulo2);
                vx2 = v0x2;
                vy2 = v0y2;
                
                x2 = x0;
                y2 = y0;
                ax2 = ax0;
                ay2 = ay0;
                index2 = 1;
                %% inicializacion de variables  RESISTENCIA DEL AIRE  variable 3
                v0x3 = v0*cos(angulo3);
                v0y3 = v0*sin(angulo3);
                vx3 = v0x3;
                vy3 = v0y3;
                
                x3 = x0;
                y3 = y0;
                ax3 = ax0;
                ay3 = ay0;
                index3 = 1;
                
                
                %% vectores de posición  RESISTENCIA DEL AIRE
                vectorx1(index1) = x1;
                vectory1(index1) = y1;
                vectorx2(index2) = x2;
                vectory2(index2) = y2;
                vectorx3(index3) = x3;
                vectory3(index3) = y3;
                
                %% Iniciliación del ciclo while  
                while y1 >= 0 &&  y2 >= 0 && y3 >= 0
                    %% RESISTENCIA DEL AIRE
                    %%Declaración del angulo anterior 
                    angulo_anterior1 = angulo1;
                    angulo_anterior2 = angulo2;
                    angulo_anterior3 = angulo3;


                    ax_anterior1 = ax1;
                    ay_anterior1 = ay1;
                    
                    ax_anterior2 = ax2;
                    ay_anterior2 = ay2;
                    
                    ax_anterior3 = ax3;
                    ay_anterior3 = ay3;
                    
                    %% Declaración de la velocidad y posición en x    RESISTENCIA DEL AIRE
                    vx_anterior1 = vx1;
                    vx1 = vx1 + .5*(ax_anterior1+ax1)*Ts;
                    x1 = x1 + vx_anterior1 * Ts + Ts^2*0.5*ax_anterior1;
                    
                    vx_anterior2 = vx2;
                    vx2 = vx2 + .5*(ax_anterior3+ax2)*Ts;
                    x2 = x2 + vx_anterior2 * Ts + Ts^2*0.5*ax_anterior2;
                    
                    vx_anterior3 = vx3;
                    vx3 = vx_anterior3 + .5*(ax_anterior3+ax3)*Ts;
                    x3 = x3 + vx_anterior3 * Ts + Ts^2*0.5*ax_anterior3;
                     
                    %% Declaración de la velocidad y posición en y    RESISTENCIA DEL AIRE
                    vy_anterior1 = vy1;
                    vy1 = vy1 + .5*(ay_anterior1+ay1)*Ts;
                    y1 = y1 + vy_anterior1 * Ts + Ts^2*0.5*ay_anterior1;
                    
                    v1 = sqrt(vx1^2+vy1^2);
                    v2 = sqrt(vx2^2+vy2^2);
                    v3 = sqrt(vx3^2+vy3^2);
                    
                    
                    vy_anterior2 = vy2;
                    vy2 = vy2 + .5*(ay_anterior2+ay2)*Ts;
                    y2 = y2 + vy_anterior2 * Ts + Ts^2*0.5*ay_anterior2;
                    
                    vy_anterior3 = vy3;
                    vy3 = vy3 + .5*(ay_anterior3+ay3)*Ts;
                    y3 = y3 + vy_anterior3 * Ts + Ts^2*0.5*ay_anterior3;
                    
                    %%Declaración de el angulo y la velocidad resultante RESISTENCIA DEL AIRE
                    angulo1 = atan(vy1/vx1);
                    angulo2 = atan(vy2/vx2);
                    angulo3 = atan(vy3/vx3);
                    
                   
                    %% declaracion de la aceleración en el eje x,y    RESISTENCIA DEL AIRE 
                    ax1 = ax_anterior1 -(KD/m1)*v1^n*cos(angulo_anterior1);
                    ay1 = ay_anterior1 -(KD/m1)*v1^n*sin(angulo_anterior1);
                    
                    ax2 = ax_anterior2 -(KD/m2)*v2^n*cos(angulo_anterior2);
                    ay2 = ay_anterior2 -(KD/m2)*v2^n*sin(angulo_anterior2);
                    
                    ax3 = ax_anterior3 -(KD/m3)*v3^n*cos(angulo_anterior3);
                    ay3 = ay_anterior3 -(KD/m3)*v3^n*sin(angulo_anterior3);
                    %% Declaración del index e introducirlos a los vectores de posición x,y  RESISTENCIA DEL AIRE
                    index1 = index1 + 1;
                    vectorx1(index1) = x1;
                    vectory1(index1) = y1;
                    
                    index2 = index2 + 1;
                    vectorx2(index2) = x2;
                    vectory2(index2) = y2;
                    
                    index3 = index3 + 1;
                    vectorx3(index3) = x3;
                    vectory3(index3) = y3;
                    %% Mostar la gráfica de posición con resistencia del aire
                    
                    plot(app.UIAxes,vectorx1,vectory1,"r",vectorx2,vectory2,"b",vectorx3,vectory3,"g");
                    pause(0.1)  
                end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {777, 777};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {324, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConstantededragEditFieldLabel = uilabel(app.LeftPanel);
            app.ConstantededragEditFieldLabel.Text = 'Constante de drag';
            app.ConstantededragEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConstantededragEditField.Value = 0.4;
            app.TssEditFieldLabel = uilabel(app.LeftPanel);
            app.TssEditFieldLabel.Text = 'Ts (s)';
            app.TssEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TssEditField.Value = 0.1;
            app.Densidaddelairekgm3EditFieldLabel = uilabel(app.LeftPanel);
            app.Densidaddelairekgm3EditFieldLabel.Text = 'Densidad del aire kg/m^3';
            app.Densidaddelairekgm3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Densidaddelairekgm3EditField.Value = 1.2;
            app.AreatransversalEditFieldLabel = uilabel(app.LeftPanel);
            app.AreatransversalEditFieldLabel.Text = 'Area transversal';
            app.AreatransversalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.velocidadinicialmsEditFieldLabel = uilabel(app.LeftPanel);
            app.velocidadinicialmsEditFieldLabel.Text = 'velocidad inicial (m/s)';
            app.velocidadinicialmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AngulogradosEditFieldLabel = uilabel(app.LeftPanel);
            app.AngulogradosEditFieldLabel.Text = 'Angulo(grados)';
            app.AngulogradosEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MasakgEditFieldLabel = uilabel(app.LeftPanel);
            app.MasakgEditFieldLabel.Text = 'Masa (kg)';
            app.MasakgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ymEditFieldLabel = uilabel(app.LeftPanel);
            app.ymEditFieldLabel.Text = 'y(m)';
            app.ymEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xmEditFieldLabel = uilabel(app.LeftPanel);
            app.xmEditFieldLabel.Text = 'x(m)';
            app.xmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlfaEditFieldLabel = uilabel(app.LeftPanel);
            app.AlfaEditFieldLabel.Text = 'Alfa';
            app.AlfaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlfaEditField.Value = 2;
            app.maxymEditFieldLabel = uilabel(app.LeftPanel);
            app.maxymEditFieldLabel.Text = 'max y (m)';
            app.maxymEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XinstantaneamEditFieldLabel = uilabel(app.LeftPanel);
            app.XinstantaneamEditFieldLabel.Text = 'X instantanea (m)';
            app.XinstantaneamEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YinstantaneamEditFieldLabel = uilabel(app.LeftPanel);
            app.YinstantaneamEditFieldLabel.Text = 'Y instantanea (m)';
            app.YinstantaneamEditField = uieditfield(app.LeftPanel, 'numeric');
            app.velocidadinstaneaXmsEditFieldLabel = uilabel(app.LeftPanel);
            app.velocidadinstaneaXmsEditFieldLabel.Text = 'velocidad instanea X (m/s)';
            app.velocidadinstaneaXmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.velocidadinstantaneaYmsEditFieldLabel = uilabel(app.LeftPanel);
            app.velocidadinstantaneaYmsEditFieldLabel.Text = 'velocidad instantanea Y (m/s)';
            app.velocidadinstantaneaYmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxxmEditFieldLabel = uilabel(app.LeftPanel);
            app.maxxmEditFieldLabel.Text = 'max x (m)';
            app.maxxmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ValoresInicialesLabel = uilabel(app.LeftPanel);
            app.ValoresInicialesLabel.Text = 'Valores Iniciales ';
            app.ValoresinstantaneosLabel = uilabel(app.LeftPanel);
            app.ValoresinstantaneosLabel.Text = 'Valores instantaneos';
            app.TiempofinaslsEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempofinaslsEditFieldLabel.Text = 'Tiempo finasl (s)';
            app.TiempofinaslsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.EmpezarButton = uibutton(app.RightPanel, 'push');
            app.EmpezarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.EmpezarButton.Text = 'Empezar';
            app.EmpezaraleatorioButton = uibutton(app.RightPanel, 'push');
            app.EmpezaraleatorioButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezaraleatorioButtonPushed, true);
            app.EmpezaraleatorioButton.Text = 'Empezar aleatorio';
            app.AleatorioTextAreaLabel = uilabel(app.RightPanel);
            app.AleatorioTextAreaLabel.Text = 'Aleatorio';
            app.AleatorioTextArea = uitextarea(app.RightPanel);
            app.AleatorioTextArea.Value = {'Para valores aleatorios de debe de introducir los siguientes valores iniciales:'; 'Constante de drag'; 'Ts'; 'Densidad del aire'; 'Area Transversal'; 'Masa (kg)'; 'y(m)'; 'x(m)'; 'Alfa'};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, ' Simulación de proyectiles de un volcán ')
            xlabel(app.UIAxes, 'X(m)')
            ylabel(app.UIAxes, 'Y(m)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end